classdef Control_robot_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        UIAxes                  matlab.ui.control.UIAxes
        ForwardPanel            matlab.ui.container.Panel
        StartButton             matlab.ui.control.Button
        DegreeLabel             matlab.ui.control.Label
        DegreeLabel_2           matlab.ui.control.Label
        DegreeLabel_3           matlab.ui.control.Label
        ForwardKinematicsLabel  matlab.ui.control.Label
        Theta1SliderLabel       matlab.ui.control.Label
        Theta1Slider            matlab.ui.control.Slider
        Theta2SliderLabel       matlab.ui.control.Label
        Theta2Slider            matlab.ui.control.Slider
        Theta3SliderLabel       matlab.ui.control.Label
        Theta3Slider            matlab.ui.control.Slider
        Theta1EditFieldLabel    matlab.ui.control.Label
        Theta1EditField         matlab.ui.control.NumericEditField
        Theta2EditFieldLabel    matlab.ui.control.Label
        Theta2EditField         matlab.ui.control.NumericEditField
        Theta3EditFieldLabel    matlab.ui.control.Label
        Theta3EditField         matlab.ui.control.NumericEditField
        ClearPathButton         matlab.ui.control.Button
        ViewPanel               matlab.ui.container.Panel
        ResponseLabel           matlab.ui.control.Label
        cmLabel                 matlab.ui.control.Label
        cmLabel_2               matlab.ui.control.Label
        cmLabel_3               matlab.ui.control.Label
        DegreeLabel_4           matlab.ui.control.Label
        DegreeLabel_5           matlab.ui.control.Label
        DegreeLabel_6           matlab.ui.control.Label
        XEditFieldLabel         matlab.ui.control.Label
        XEditField              matlab.ui.control.NumericEditField
        YEditFieldLabel         matlab.ui.control.Label
        YEditField              matlab.ui.control.NumericEditField
        ZEditFieldLabel         matlab.ui.control.Label
        ZEditField              matlab.ui.control.NumericEditField
        RollEditFieldLabel      matlab.ui.control.Label
        RollEditField           matlab.ui.control.NumericEditField
        PitchEditFieldLabel     matlab.ui.control.Label
        PitchEditField          matlab.ui.control.NumericEditField
        YawEditFieldLabel       matlab.ui.control.Label
        YawEditField            matlab.ui.control.NumericEditField
        CoordiateCheckBox       matlab.ui.control.CheckBox
        OpacitySliderLabel      matlab.ui.control.Label
        OpacitySlider           matlab.ui.control.Slider
        OpacityButton           matlab.ui.control.StateButton
        InversePanel            matlab.ui.container.Panel
        InverseKinematicsLabel  matlab.ui.control.Label
        cmLabel_4               matlab.ui.control.Label
        cmLabel_5               matlab.ui.control.Label
        cmLabel_6               matlab.ui.control.Label
        InverseButton           matlab.ui.control.Button
        XEditField_2Label       matlab.ui.control.Label
        XEditField_2            matlab.ui.control.NumericEditField
        YEditField_2Label       matlab.ui.control.Label
        YEditField_2            matlab.ui.control.NumericEditField
        ZEditField_2Label       matlab.ui.control.Label
        ZEditField_2            matlab.ui.control.NumericEditField
        UIAxes2                 matlab.ui.control.UIAxes
        UIAxes3                 matlab.ui.control.UIAxes
        UIAxes4                 matlab.ui.control.UIAxes
        UIAxes2_2               matlab.ui.control.UIAxes
        UIAxes3_2               matlab.ui.control.UIAxes
        UIAxes4_2               matlab.ui.control.UIAxes
        UIAxes2_3               matlab.ui.control.UIAxes
        UIAxes3_3               matlab.ui.control.UIAxes
        UIAxes4_3               matlab.ui.control.UIAxes
        TextArea                matlab.ui.control.TextArea
    end


    properties (Access = private)
        theta1 = 0;
        theta2 = 0;
        theta3 = 0;
        
        newTheta1 = 0;
        newTheta2 = 0;
        newTheta3 = 0;
        
        theta = [0 0 0];
        degreeRes = [0 0 0];
        
        x_ref = 0;
        y_ref = 0;
        z_ref = 0;
        
        % Transformation matrices (initially identity matrices)
        T0_0 = eye(4);
        T0_1;
        T0_2;
        T0_3;
        
        % Joint
        joint0;
        joint1;
        joint2;
        joint3;
        
        
        c3;
        s3 = [];
        c2 = [];
        s2 = [];
    end
    
    properties (Constant)
        r1 = 10;    % Link radius
        r2 = 15;    % Joint radius
        r3 = 25;    % Base radius
        h_base = 20;        % Base height
        h_joint = 40;       % Joint height
        
        % Link, Joints lengths or distances
        d1 = 80;
        a2 = 60;
        a3 = 50;
    end

    methods (Static)
        %% Tranformation matrices function
        function matrixDH = T(d, theta, a, alpha)
        matrixDH = [cos(theta) -cos(alpha)*sin(theta) sin(alpha)*sin(theta) a*cos(theta)
             sin(theta) cos(alpha)*cos(theta) -sin(alpha)*cos(theta) a*sin(theta)
                 0          sin(alpha)             cos(alpha)           d
                 0              0                      0                1 ];
        end
    end
    
    methods (Access = private) 
        %% Display 3D Robot
        function path = mlsDisplayRobot(app, path)
            % Plot the robot in app UIAxes
            cla(app.UIAxes);  % Clear previous plot
            hold(app.UIAxes, 'on');
            grid(app.UIAxes, 'on');
%             axis(app.UIAxes, 'equal');
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            zlabel(app.UIAxes, 'Z');
            title(app.UIAxes, 'Robotics Anh Vu 3-Dof');
            xlim(app.UIAxes, [-200, 200]);
            ylim(app.UIAxes, [-200, 200]);
            zlim(app.UIAxes, [0, 280]);
            
            % Tao khoi O_0
            [x1, y1, z1] = cylinder(app.r3);        % Base O_0
            z1 = z1 * app.h_base;
            [x2, y2, z2] = cylinder(app.r2);        % Joint O_0
            z2 = z2 * app.h_joint;
            [x3, y3, z3] = cylinder(app.r1);        % Link O_0
            z3 = z3 * app.d1;
            
            % Tao khoi O_1
            [x4, y4, z4] = cylinder(app.r2);        % Joint O_1
            z4 = z4 * app.h_joint;
            [x5, y5, z5] = cylinder(app.r1);        % Link O_1
            
            % Tao khoi O_2
            [x6, y6, z6] = cylinder(app.r2);        % Joint O_2
            z6 = z6 * app.h_joint;
            [x7, y7, z7] = cylinder(app.r1);        % Link O_2
            
            % Tao khoi End_effector
            x_e = [20,30,40,50,60,60,50,40,40,40,50,60,60,50,40,30,20,20,20,20,20,20];
            x_e = x_e - 20;
            z_e = [-30,-30,-30,-30,-20,-10,-10,-10,0,10,10,10,20,30,30,30,30,20,10,0,-10,-20];
            y_e = 10*ones(1,size(x_e,2));
            y_e(2,:)= -10*ones(1,size(x_e,2));
            
            x_e = repmat(x_e, 2, 1);
            z_e = repmat(z_e, 2, 1); 
            
            H = [x_e(1,:); y_e(1,:); z_e(1,:); ones(1,size(x_e,2))];
            R = [x_e(2,:); y_e(2,:); z_e(2,:); ones(1,size(x_e,2))];
            
            % Xoay 90 do => link vuong goc joint
            rotY = [cos(pi/2)  0 sin(pi/2) 0; 
                    0          1 0         0; 
                    -sin(pi/2) 0 cos(pi/2) 0; 
                    0          0 0         1];
            
            % Transformation matrices
            app.T0_0 = eye(4);
            app.T0_1 = T(app.d1, app.theta1, 0, pi/2);
            T1_2 = T(0, app.theta2, app.a2, 0);
            T2_3 = T(0, app.theta3, app.a3, 0);
            
            app.T0_2 = app.T0_1 * T1_2;
            app.T0_3 = app.T0_2 * T2_3;
             
            app.joint0 = app.T0_0(1:3, 4);  % Base origin
            app.joint1 = app.T0_1(1:3, 4); % Joint 1
            app.joint2 = app.T0_2(1:3, 4); % Joint 2
            %app.joint3 = app.T0_3(1:3, 4); % Joint 3 (end effector)
            
            % Joint 1
            for j = 1:length(x2)  
                for i = 1:2  
                    Pos2 = [x2(i, j); y2(i, j); z2(i, j) - app.h_joint/2 ; 1];          % Joint O_0 
                    Pos4 = app.T0_1 * Pos2;                                             % Joint O_1
                    x4(i, j) = Pos4(1);
                    y4(i, j) = Pos4(2);
                    z4(i, j) = Pos4(3);
                end
            end
            
            % Link 1
            for j = 1:length(x3)  
                for i = 1:2  
                    Pos3 = [x3(i, j); y3(i, j); -z3(i, j)*app.a2/app.d1; 1];            % Link O_0
                    Pos5 = app.T0_2 * rotY * Pos3;                                      % Link O_1
                    x5(i, j) = Pos5(1);
                    y5(i, j) = Pos5(2);
                    z5(i, j) = Pos5(3);
                end
            end
            
            % Joint 2
            for j = 1:length(x2)  
                for i = 1:2  
                    Pos2 = [x2(i, j); y2(i, j); z2(i, j) - app.h_joint/2 ; 1];          % Joint O_0 
                    Pos6 = app.T0_2 * Pos2;                                             % Joint O_2
                    x6(i, j) = Pos6(1);
                    y6(i, j) = Pos6(2);
                    z6(i, j) = Pos6(3);
                end
            end
            
            % Link 2
            for j = 1:length(x3)  
                for i = 1:2  
                    Pos3 = [x3(i, j); y3(i, j); -z3(i, j)*app.a3/app.d1; 1];            % Link O_0
                    Pos7 = app.T0_3 * rotY * Pos3;                                      % Link O_2
                    x7(i, j) = Pos7(1);
                    y7(i, j) = Pos7(2);
                    z7(i, j) = Pos7(3);
                end
            end
            
            %End effector
            H_new = app.T0_3*R;
            R_new = app.T0_3*H;
            x_e(1,:) = H_new(1,:);x_e(2,:) = R_new(1,:);
            y_e(1,:) = H_new(2,:);y_e(2,:) = R_new(2,:);
            z_e(1,:) = H_new(3,:);z_e(2,:) = R_new(3,:);
            
            % Draw the robot using surf and fill3, just as in the original code
            % Base
            surf(app.UIAxes, x1, y1, z1, 'EdgeColor', 'none', 'FaceColor', 'r'); 
            fill3(app.UIAxes, x1(1,:), y1(1,:), z1(1,:), 'r');
            fill3(app.UIAxes, x1(2,:), y1(2,:), z1(2,:), 'r');
            
            % Joint 0 - Blue
            surf(app.UIAxes, x2, y2, z2, 'EdgeColor', 'none', 'FaceColor', 'b');
            fill3(app.UIAxes, x2(1,:), y2(1,:), z2(1,:), 'b');
            fill3(app.UIAxes, x2(2,:), y2(2,:), z2(2,:), 'b');
            
            % Link 0 - Yellow
            surf(app.UIAxes, x3, y3, z3, 'EdgeColor', 'none', 'FaceColor', 'y');
            fill3(app.UIAxes, x3(1,:), y3(1,:), z3(1,:), 'y');
            fill3(app.UIAxes, x3(2,:), y3(2,:), z3(2,:), 'y');
            
            % Joint 1 - Blue
            surf(app.UIAxes, x4, y4, z4, 'EdgeColor', 'none', 'FaceColor', 'b');
            fill3(app.UIAxes, x4(1,:), y4(1,:), z4(1,:), 'b');
            fill3(app.UIAxes, x4(2,:), y4(2,:), z4(2,:), 'b');
            
            % Link 1 - Yellow
            surf(app.UIAxes, x5, y5, z5, 'EdgeColor', 'none', 'FaceColor', 'y');
            fill3(app.UIAxes, x5(1,:), y5(1,:), z5(1,:), 'y');
            fill3(app.UIAxes, x5(2,:), y5(2,:), z5(2,:), 'y');
            
            % Joint 2 - Blue
            surf(app.UIAxes, x6, y6, z6, 'EdgeColor', 'none', 'FaceColor', 'b');
            fill3(app.UIAxes, x6(1,:), y6(1,:), z6(1,:), 'b');
            fill3(app.UIAxes, x6(2,:), y6(2,:), z6(2,:), 'b');
            
            % Link 2 - Yellow
            surf(app.UIAxes, x7, y7, z7, 'EdgeColor', 'none', 'FaceColor', 'y');
            fill3(app.UIAxes, x7(1,:), y7(1,:), z7(1,:), 'y');
            fill3(app.UIAxes, x7(2,:), y7(2,:), z7(2,:), 'y');
            
            % End effector - Gree - Red
            surf(app.UIAxes, x_e, y_e, z_e, 'FaceColor', 'r');
            fill3(app.UIAxes, x_e(1, :), y_e(1,:), z_e(1,:),'g');
            fill3(app.UIAxes, x_e(2, :), y_e(2,:), z_e(2,:),'g');
            
            x_p = 30;
            y_p = 0;
            z_p = 0;
            P = [x_p;y_p;z_p;1];
            P = app.T0_3 * P;
            app.XEditField.Value = P(1,1);
            app.YEditField.Value = P(2,1);
            app.ZEditField.Value = P(3,1);
            
            % Plot joint positions (O0, O1, O2, O3)
            plot3(app.UIAxes, app.joint0(1), app.joint0(2), app.joint0(3), 'ro', 'MarkerSize', 2, 'MarkerFaceColor', 'r');
            text(app.UIAxes, app.joint0(1), app.joint0(2), app.joint0(3), 'O_0', 'VerticalAlignment', 'bottom');
            plot3(app.UIAxes, app.joint1(1), app.joint1(2), app.joint1(3), 'ro', 'MarkerSize', 2, 'MarkerFaceColor', 'r');
            text(app.UIAxes, app.joint1(1), app.joint1(2), app.joint1(3), 'O_1', 'VerticalAlignment', 'bottom');
            plot3(app.UIAxes, app.joint2(1), app.joint2(2), app.joint2(3), 'ro', 'MarkerSize', 2, 'MarkerFaceColor', 'r');
            text(app.UIAxes, app.joint2(1), app.joint2(2), app.joint2(3), 'O_2', 'VerticalAlignment', 'bottom');
            plot3(app.UIAxes, P(1,1), P(2,1), P(3,1), 'ro', 'MarkerSize', 7, 'MarkerFaceColor', 'r');
            text(app.UIAxes, P(1,1), P(2,1), P(3,1), 'O_3', 'VerticalAlignment', 'bottom');
            
            [~, n] = size(path);
            % append path
            path(1,n+1) = P(1,1);
            path(2,n+1) = P(2,1);
            path(3,n+1) = P(3,1);
            % draw path
            x = path(1,:);
            y = path(2,:);
            z = path(3,:);
            plot3(app.UIAxes,x,y,z,'LineWidth',2);
            
            cal = sqrt((app.T0_3(3,2))^2 + (app.T0_3(3,3))^2);
            pitch = atan2(-app.T0_3(3,1),cal);
            if pitch == pi/2
                yaw = 0;
                roll = atan2(app.T0_3(1,2),app.T0_3(2,2));
            elseif pitch == -pi/2
                yaw = 0;
                roll = -atan2(app.T0_3(1,2),app.T0_3(2,2));
            else
                yaw = atan2(app.T0_3(2,1)/cos(pitch), app.T0_3(1,1)/cos(pitch));
                roll = atan2(app.T0_3(3,2)/cos(pitch), app.T0_3(3,3)/cos(pitch));
            end
            app.degreeRes = [roll pitch yaw]*(180/pi);
            
            app.RollEditField.Value = app.degreeRes(1);
            app.PitchEditField.Value = app.degreeRes(2);
            app.YawEditField.Value = app.degreeRes(3);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global path;
            rotate3d(app.UIAxes, 'on');
            path = app.mlsDisplayRobot(path);
        end

        % Value changed function: Theta1Slider
        function Theta1SliderValueChanged(app, event)
            app.Theta1EditField.Value =  app.Theta1Slider.Value;
        end

        % Value changed function: Theta2Slider
        function Theta2SliderValueChanged(app, event)
            app.Theta2EditField.Value =  app.Theta2Slider.Value;
        end

        % Value changed function: Theta3Slider
        function Theta3SliderValueChanged(app, event)
            app.Theta3EditField.Value =  app.Theta3Slider.Value;
        end

        % Button pushed function: StartButton
        function FowardStartPushed(app, event)
            global path;
            app.theta1 = app.newTheta1;
            app.newTheta1 = deg2rad(app.Theta1Slider.Value);

            app.theta2 = app.newTheta2;
            app.newTheta2 = deg2rad(app.Theta2Slider.Value);
            
            app.theta3 = app.newTheta3;
            app.newTheta3 = deg2rad(app.Theta3Slider.Value);
            
            % Chuyen dong theo theta1
            if app.theta1 ~= app.newTheta1
                theta1Array = linspace(app.theta1,app.newTheta1,20);
                for i = 1:20
                    app.theta1 = theta1Array(i);
                    path = app.mlsDisplayRobot(path);
                    pause(0.1);
                end
            end
            % Chuyen dong theo theta2
            if app.theta2 ~= app.newTheta2
                theta2Array = linspace(app.theta2,app.newTheta2,20);
                for i = 1:20
                    app.theta2 = theta2Array(i);
                    path = app.mlsDisplayRobot(path);
                    pause(0.1);
                end
            end
            % Chuyen dong theo theta3
            if app.theta3 ~= app.newTheta3
                theta3Array = linspace(app.theta3,app.newTheta3,20);
                for i = 1:20
                    app.theta3 = theta3Array(i);
                    path = app.mlsDisplayRobot(path);
                    pause(0.1);
                end
            end
        end

        % Value changed function: Theta1EditField
        function Theta1EditFieldValueChanged(app, event)
            app.Theta1Slider.Value = app.Theta1EditField.Value;
        end

        % Value changed function: Theta2EditField
        function Theta2EditFieldValueChanged(app, event)
            app.Theta2Slider.Value = app.Theta2EditField.Value;
        end

        % Value changed function: Theta3EditField
        function Theta3EditFieldValueChanged(app, event)
            app.Theta3Slider.Value = app.Theta3EditField.Value;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            clearvars -global path;
            delete(app)
        end

        % Value changed function: XEditField_2
        function CallbackInverseXEdit(app, event)
            app.x_ref = app.XEditField_2.Value;
            
        end

        % Value changed function: YEditField_2
        function CallbackInverseYEdit(app, event)
            app.y_ref = app.YEditField_2.Value;
            
        end

        % Value changed function: ZEditField_2
        function CallbackInverseZEdit(app, event)
            app.z_ref = app.ZEditField_2.Value - app.d1;
            
        end

        % Button pushed function: InverseButton
        function InverseKinematicButton(app, event)
            m = app.a3 + 30;
            %Calculate theta 1
            app.theta(1) = atan2(app.y_ref, app.x_ref);
            
            % Calculate intermediate values for theta2 and theta3
            pwx2_pwy2 = app.x_ref^2 + app.y_ref^2;
            pwx2_pwy2_pwz2 = app.z_ref^2 + pwx2_pwy2;
            
            % Calculate theta3 using c3 and s3
            app.c3 = (pwx2_pwy2_pwz2 - app.a2^2 - m^2) / (2 * app.a2 * m);
            app.s3 = [sqrt(1 - app.c3^2); -sqrt(1 - app.c3^2)];
            
            if app.z_ref >= 0
                app.theta(3) = atan2(app.s3(1), app.c3);

                % Calculate theta2 using c2 and s2
                app.c2 = (sqrt(pwx2_pwy2) * (app.a2 + m * app.c3) + app.z_ref * m * app.s3(1)) / (app.a2^2 + m^2 + 2 * app.a2 * m * app.c3);
                app.s2 = ((-sqrt(pwx2_pwy2) * m * app.s3(1))+ app.z_ref * (app.a2 + m * app.c3))  / (app.a2^2 + m^2 + 2 * app.a2 * m * app.c3);
                app.theta(2) = rad2deg(atan2(app.s2, app.c2));

                if (app.theta(2) <-30 || app.theta(2) > 90 )
                app.c2 = (-sqrt(pwx2_pwy2) * (app.a2 + m * app.c3) + app.z_ref * m * app.s3(1)) / (app.a2^2 + m^2 + 2 * app.a2 * m * app.c3);
                app.s2 = ((sqrt(pwx2_pwy2) * m * app.s3(1))+ app.z_ref * (app.a2 + m * app.c3))  / (app.a2^2 + m^2 + 2 * app.a2 * m * app.c3);
                app.theta(2) = rad2deg(atan2(app.s2, app.c2));
                end
            else
                app.theta(3) = atan2(app.s3(2), app.c3);
                
                app.c2 = (sqrt(pwx2_pwy2) * (app.a2 + m * app.c3) + app.z_ref * m * app.s3(2)) / (app.a2^2 + m^2 + 2 * app.a2 * m * app.c3);
                app.s2 = ((-sqrt(pwx2_pwy2) * m * app.s3(2))+ app.z_ref * (app.a2 + m*app.c3))  / (app.a2^2 + m^2 + 2 * app.a2 * m * app.c3);
                app.theta(2) = rad2deg(atan2(app.s2, app.c2));

                if (app.theta(2) <-30 || app.theta(2) > 90)
                app.c2 = (-sqrt(pwx2_pwy2) * (app.a2 + m * app.c3) + app.z_ref * m * app.s3(2)) / (app.a2^2 + m^2 + 2 * app.a2 * m * app.c3);
                app.s2 = ((sqrt(pwx2_pwy2) * m * app.s3(2))+ app.z_ref * (app.a2 + m * app.c3))  / (app.a2^2 + m^2 + 2 * app.a2 * m * app.c3);
                app.theta(2) = rad2deg(atan2(app.s2, app.c2));
                end
                
            end
            app.theta(2) = atan2(app.s2, app.c2);
            
            app.theta1 = app.newTheta1;
            app.newTheta1 = app.theta(1);
            app.Theta1Slider.Value = rad2deg(app.newTheta1);
            app.Theta1EditField.Value = app.Theta1Slider.Value;
            
            app.theta2 = app.newTheta2;
            app.newTheta2 = app.theta(2);
            app.Theta2Slider.Value = rad2deg(app.newTheta2);
            app.Theta2EditField.Value = app.Theta2Slider.Value;
            
            app.theta3 = app.newTheta3;
            app.newTheta3 = app.theta(3);
            app.Theta3Slider.Value = rad2deg(app.newTheta3);
            app.Theta3EditField.Value = app.Theta3Slider.Value;
            
            global path;
            % Chuyen dong theo theta1
            if app.newTheta1 ~= app.theta1
                theta1Array = linspace(app.theta1,app.newTheta1,20);
                for i = 1:20
                    app.theta1 = theta1Array(i);
                    path = app.mlsDisplayRobot(path);
                    pause(0.1);
                end
            end
            % Chuyen dong theo theta2
            if app.newTheta2 ~= app.theta2
                theta2Array = linspace(app.theta2,app.newTheta2,20);
                for i = 1:20
                    app.theta2 = theta2Array(i);
                    path = app.mlsDisplayRobot(path);
                    pause(0.1);
                end
            end
            % Chuyen dong theo theta3
            if app.newTheta3 ~= app.theta3
                theta3Array = linspace(app.theta3,app.newTheta3,20);
                for i = 1:20
                    app.theta3 = theta3Array(i);
                    path = app.mlsDisplayRobot(path);
                    pause(0.1);
                end
            end
        end

        % Button pushed function: ClearPathButton
        function CallbackClearPath(app, event)
            global path;
            %clearvars -global path;
            path = [];
            path = app.mlsDisplayRobot(path);
        end

        % Value changed function: CoordiateCheckBox
        function CoordiateCheckBoxValueChanged(app, event)
            value = app.CoordiateCheckBox.Value;
            if value
                
            else    
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Robotics')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [-200 200];
            app.UIAxes.YLim = [-200 200];
            app.UIAxes.ZLim = [0 280];
            app.UIAxes.CLim = [0 1];
            app.UIAxes.XTick = [-200 -100 0 100 200];
            app.UIAxes.YTick = [-200 -100 0 100 200];
            app.UIAxes.ZTick = [-200 -100 0 100 200];
            app.UIAxes.ZTickLabel = {'-200'; '-100'; '0'; '100'; '200'};
            app.ForwardPanel = uipanel(app.UIFigure);
            app.ForwardPanel.Title = 'Forward';
            app.StartButton = uibutton(app.ForwardPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @FowardStartPushed, true);
            app.StartButton.Text = 'Start';
            app.DegreeLabel = uilabel(app.ForwardPanel);
            app.DegreeLabel.Text = 'Degree';
            app.DegreeLabel_2 = uilabel(app.ForwardPanel);
            app.DegreeLabel_2.Text = 'Degree';
            app.DegreeLabel_3 = uilabel(app.ForwardPanel);
            app.DegreeLabel_3.Text = 'Degree';
            app.ForwardKinematicsLabel = uilabel(app.ForwardPanel);
            app.ForwardKinematicsLabel.Text = 'Forward Kinematics';
            app.Theta1SliderLabel = uilabel(app.ForwardPanel);
            app.Theta1SliderLabel.Text = 'Theta 1';
            app.Theta1Slider = uislider(app.ForwardPanel);
            app.Theta1Slider.Limits = [-180 180];
            app.Theta1Slider.ValueChangedFcn = createCallbackFcn(app, @Theta1SliderValueChanged, true);
            app.Theta2SliderLabel = uilabel(app.ForwardPanel);
            app.Theta2SliderLabel.Text = 'Theta 2';
            app.Theta2Slider = uislider(app.ForwardPanel);
            app.Theta2Slider.Limits = [-30 90];
            app.Theta2Slider.ValueChangedFcn = createCallbackFcn(app, @Theta2SliderValueChanged, true);
            app.Theta3SliderLabel = uilabel(app.ForwardPanel);
            app.Theta3SliderLabel.Text = 'Theta 3';
            app.Theta3Slider = uislider(app.ForwardPanel);
            app.Theta3Slider.Limits = [-90 90];
            app.Theta3Slider.ValueChangedFcn = createCallbackFcn(app, @Theta3SliderValueChanged, true);
            app.Theta1EditFieldLabel = uilabel(app.ForwardPanel);
            app.Theta1EditFieldLabel.Text = 'Theta 1';
            app.Theta1EditField = uieditfield(app.ForwardPanel, 'numeric');
            app.Theta1EditField.ValueChangedFcn = createCallbackFcn(app, @Theta1EditFieldValueChanged, true);
            app.Theta2EditFieldLabel = uilabel(app.ForwardPanel);
            app.Theta2EditFieldLabel.Text = 'Theta 2';
            app.Theta2EditField = uieditfield(app.ForwardPanel, 'numeric');
            app.Theta2EditField.ValueChangedFcn = createCallbackFcn(app, @Theta2EditFieldValueChanged, true);
            app.Theta3EditFieldLabel = uilabel(app.ForwardPanel);
            app.Theta3EditFieldLabel.Text = 'Theta 3';
            app.Theta3EditField = uieditfield(app.ForwardPanel, 'numeric');
            app.Theta3EditField.ValueChangedFcn = createCallbackFcn(app, @Theta3EditFieldValueChanged, true);
            app.ClearPathButton = uibutton(app.ForwardPanel, 'push');
            app.ClearPathButton.ButtonPushedFcn = createCallbackFcn(app, @CallbackClearPath, true);
            app.ClearPathButton.Text = 'Clear Path';
            app.ViewPanel = uipanel(app.UIFigure);
            app.ViewPanel.Title = 'View';
            app.ResponseLabel = uilabel(app.ViewPanel);
            app.ResponseLabel.Text = 'Response';
            app.cmLabel = uilabel(app.ViewPanel);
            app.cmLabel.Text = 'cm';
            app.cmLabel_2 = uilabel(app.ViewPanel);
            app.cmLabel_2.Text = 'cm';
            app.cmLabel_3 = uilabel(app.ViewPanel);
            app.cmLabel_3.Text = 'cm';
            app.DegreeLabel_4 = uilabel(app.ViewPanel);
            app.DegreeLabel_4.Text = 'Degree';
            app.DegreeLabel_5 = uilabel(app.ViewPanel);
            app.DegreeLabel_5.Text = 'Degree';
            app.DegreeLabel_6 = uilabel(app.ViewPanel);
            app.DegreeLabel_6.Text = 'Degree';
            app.XEditFieldLabel = uilabel(app.ViewPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.ViewPanel, 'numeric');
            app.YEditFieldLabel = uilabel(app.ViewPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.ViewPanel, 'numeric');
            app.ZEditFieldLabel = uilabel(app.ViewPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.ViewPanel, 'numeric');
            app.RollEditFieldLabel = uilabel(app.ViewPanel);
            app.RollEditFieldLabel.Text = 'Roll';
            app.RollEditField = uieditfield(app.ViewPanel, 'numeric');
            app.PitchEditFieldLabel = uilabel(app.ViewPanel);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.PitchEditField = uieditfield(app.ViewPanel, 'numeric');
            app.YawEditFieldLabel = uilabel(app.ViewPanel);
            app.YawEditFieldLabel.Text = 'Yaw';
            app.YawEditField = uieditfield(app.ViewPanel, 'numeric');
            app.CoordiateCheckBox = uicheckbox(app.ViewPanel);
            app.CoordiateCheckBox.ValueChangedFcn = createCallbackFcn(app, @CoordiateCheckBoxValueChanged, true);
            app.CoordiateCheckBox.Text = 'Coordiate';
            app.OpacitySliderLabel = uilabel(app.ViewPanel);
            app.OpacitySliderLabel.Text = 'Opacity';
            app.OpacitySlider = uislider(app.ViewPanel);
            app.OpacityButton = uibutton(app.ViewPanel, 'state');
            app.OpacityButton.Text = 'Opacity';
            app.InversePanel = uipanel(app.UIFigure);
            app.InversePanel.Title = 'Inverse';
            app.InverseKinematicsLabel = uilabel(app.InversePanel);
            app.InverseKinematicsLabel.Text = 'Inverse Kinematics';
            app.cmLabel_4 = uilabel(app.InversePanel);
            app.cmLabel_4.Text = 'cm';
            app.cmLabel_5 = uilabel(app.InversePanel);
            app.cmLabel_5.Text = 'cm';
            app.cmLabel_6 = uilabel(app.InversePanel);
            app.cmLabel_6.Text = 'cm';
            app.InverseButton = uibutton(app.InversePanel, 'push');
            app.InverseButton.ButtonPushedFcn = createCallbackFcn(app, @InverseKinematicButton, true);
            app.InverseButton.Text = 'Inverse';
            app.XEditField_2Label = uilabel(app.InversePanel);
            app.XEditField_2Label.Text = 'X';
            app.XEditField_2 = uieditfield(app.InversePanel, 'numeric');
            app.XEditField_2.ValueChangedFcn = createCallbackFcn(app, @CallbackInverseXEdit, true);
            app.YEditField_2Label = uilabel(app.InversePanel);
            app.YEditField_2Label.Text = 'Y';
            app.YEditField_2 = uieditfield(app.InversePanel, 'numeric');
            app.YEditField_2.ValueChangedFcn = createCallbackFcn(app, @CallbackInverseYEdit, true);
            app.ZEditField_2Label = uilabel(app.InversePanel);
            app.ZEditField_2Label.Text = 'Z';
            app.ZEditField_2 = uieditfield(app.InversePanel, 'numeric');
            app.ZEditField_2.ValueChangedFcn = createCallbackFcn(app, @CallbackInverseZEdit, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.UIAxes2_2 = uiaxes(app.UIFigure);
            title(app.UIAxes2_2, 'Title')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            app.UIAxes3_2 = uiaxes(app.UIFigure);
            title(app.UIAxes3_2, 'Title')
            xlabel(app.UIAxes3_2, 'X')
            ylabel(app.UIAxes3_2, 'Y')
            app.UIAxes4_2 = uiaxes(app.UIFigure);
            title(app.UIAxes4_2, 'Title')
            xlabel(app.UIAxes4_2, 'X')
            ylabel(app.UIAxes4_2, 'Y')
            app.UIAxes2_3 = uiaxes(app.UIFigure);
            title(app.UIAxes2_3, 'Title')
            xlabel(app.UIAxes2_3, 'X')
            ylabel(app.UIAxes2_3, 'Y')
            app.UIAxes3_3 = uiaxes(app.UIFigure);
            title(app.UIAxes3_3, 'Title')
            xlabel(app.UIAxes3_3, 'X')
            ylabel(app.UIAxes3_3, 'Y')
            app.UIAxes4_3 = uiaxes(app.UIFigure);
            title(app.UIAxes4_3, 'Title')
            xlabel(app.UIAxes4_3, 'X')
            ylabel(app.UIAxes4_3, 'Y')
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Nguyen Lam Anh Vu'; 'MSSV: 2112670'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = Control_robot_gui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end